classdef voice_collect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Slider2       matlab.ui.control.Slider
        Slider2Label  matlab.ui.control.Label
        Slider        matlab.ui.control.Slider
        SliderLabel   matlab.ui.control.Label
        stopButton    matlab.ui.control.Button
        runButton     matlab.ui.control.Button
        UIAxes2       matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        voice=audiorecorder(40000,8,1,-1);
        UI_run;
    end
   
    methods (Access = public)
        function my_plot(app)
            Fs=40000;
            voice_data=getaudiodata(app.voice,'int8');
            N=length(voice_data);
            dt=1/Fs;
            T=dt*N;
            t=linspace(0,T,N);
            ff=fft(voice_data,N);
            yff=2*abs(ff(1:N/2+1))/N;
            F=Fs*(1:N/2+1)/N;
            plot(app.UIAxes,t,voice_data);
            plot(app.UIAxes2,F,yff);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            title(app.UIAxes,'声音信号');
            xlabel(app.UIAxes,'频率/HZ');
            ylabel(app.UIAxes,'幅值/V');
            title(app.UIAxes2,'频谱图');
            xlabel(app.UIAxes2,'时间/t');
            ylabel(app.UIAxes2,'幅值/V')
            app.UI_run=timer;
            app.UI_run.StartDelay = 0.1; % 开启的延时生效时间
            app.UI_run.Period = 1.0;% 周期
            app.UI_run.BusyMode='queue';
            app.UI_run.ExecutionMode = 'fixedRate';
            app.UI_run.TimerFcn = @(~, ~) my_plot(app);
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            record(app.voice);
            start(app.UI_run)
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            stop(app.UI_run)
            my_plot(app);
            stop(app.voice);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            set(app.UIAxes,'ylim',[-changingValue,changingValue])
        end

        % Value changing function: Slider2
        function Slider2ValueChanging(app, event)
            changingValue = event.Value;
            set(app.UIAxes2,'ylim',[0,changingValue])
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.UI_run);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-5 5];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YLim = [0 0.05];
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [5 150];
            app.Slider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150];
            app.Slider.MajorTickLabels = {'0', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50', '55', '60', '65', '70', '75', '80', '85', '90', '95', '100', '105', '110', '115', '120', '125', '130', '135', '140', '145'};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 5;
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [0.01 5];
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.Slider2.Value = 0.05;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = voice_collect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end